classdef VULCAN_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        VULCANGUIPanel                matlab.ui.container.Panel
        TabGroup                      matlab.ui.container.TabGroup
        DrillTab                      matlab.ui.container.Tab
        DrillControlsButtonGroup      matlab.ui.container.ButtonGroup
        STARTButton                   matlab.ui.control.ToggleButton
        STOPButton                    matlab.ui.control.ToggleButton
        ToolChangeButton              matlab.ui.control.ToggleButton
        ToolChangeButtonGroup         matlab.ui.container.ButtonGroup
        Bit1Button                    matlab.ui.control.ToggleButton
        Bit2Button                    matlab.ui.control.ToggleButton
        Bit3Button                    matlab.ui.control.ToggleButton
        Bit4Button                    matlab.ui.control.ToggleButton
        UITable                       matlab.ui.control.Table
        AmperageGaugeLabel            matlab.ui.control.Label
        AmperageGauge                 matlab.ui.control.SemicircularGauge
        UIAxes                        matlab.ui.control.UIAxes
        HeatProbeTab                  matlab.ui.container.Tab
        HeatProbeControlsButtonGroup  matlab.ui.container.ButtonGroup
        STARTButton_2                 matlab.ui.control.ToggleButton
        STOPButton_2                  matlab.ui.control.ToggleButton
        ToolChangeButton_2            matlab.ui.control.ToggleButton
        PumpTab                       matlab.ui.container.Tab
        PumpControlButtonGroup        matlab.ui.container.ButtonGroup
        STARTButton_3                 matlab.ui.control.ToggleButton
        STOPButton_3                  matlab.ui.control.ToggleButton
    end

    
    properties (Access = private)
        a; % Description
        stop;
        done = 0;
        h;
        timeLogs;
        AmperageLogs;
        TimeSecs;
        Amp;
        ToolChange;
        s;
        n;
        t;
    end
    
    properties (Access = public)
        digital_core_data; % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.t = table;
           app.n = 0;
           app.a = serialport('COM7',115200);
           configureTerminator(app.a,255);
           app.s = servo(app.a, 'D9');
           app.h = animatedline(app.UIAxes);
           app.ToolChangeButtonGroup.Visible = 'off';
           app.digital_core_data = cell(100,2); %empty cell array, where 1st column is time started, second is arrays for digital core.
        end

        % Selection changed function: DrillControlsButtonGroup
        function DrillControlsButtonGroupSelectionChanged(app, event)

            app.ToolChange = 0;
            %selectedButton = app.DrillControlsButtonGroup.SelectedObject;
            if(app.STARTButton.Value)
                app.n = app.n+1; %count for digital core tracking
                app.ToolChangeButton.Visible = 'off';
                app.ToolChangeButtonGroup.Visible = 'off';
                writeline(app.a,'1');%Turn on Z Motor
                
                %Digital Core
                app.stop = 0;
                app.done = 0;
                clearpoints(app.h);
                ax = gca;
                ax.YGrid = 'on';
                ax.YLim = [0 750];
                startTime = datetime;
                app.digital_core_data{app.n,1}= startTime;
                
                
                %Get current time
                sec = 0;
                while ~app.stop 
                Voltage = readVoltage(app.a,'A0');                
                d = datetime;
                duration = seconds(d - startTime); %Current time - start time]
                addpoints(app.h, duration,Voltage) %Add points to animation
                
                if mod(sec,5) == 0 %Log data every 20 loops - Add average value?
                    nr = {startTime, duration, Voltage};
                    app.UITable.Data = [app.t;nr];
                    app.t = app.UITable.Data; %table stores all previous data - Make text file?
                    %Write to text file to be analyzed later - N force and
                    %each z-value
                end
                %ax.XLim = datenum([time-seconds(15) time]); %Update Axis
                %datetick('x','keeplimits')
                
                drawnow
                
                app.stop = app.done;
                sec = sec + 1;
                end
                
        
            elseif(app.STOPButton.Value)
                app.ToolChangeButton.Visible = 'on';
                app.ToolChangeButtonGroup.Visible = 'off';
                app.done = 1; %Stop digital core
                writeDigitalPin(app.a, 'D5',0); %Turn off z motor
                writeDigitalPin(app.a, 'D3',0); %Turn off drill 
                
            elseif(app.ToolChangeButton.Value)
                app.ToolChangeButtonGroup.Visible = 'on';
                app.done = 1; %Stop digital core
                writeDigitalPin(app.a, 'D5',0); %Turn off z motor
                writeDigitalPin(app.a, 'D3',0); %Turn off drill   
            end
            
        end

        % Callback function
        function SliderValueChanged(app, event)
    
        end

        % Callback function
        function SliderValueChanged2(app, event)
            
        end

        % Callback function
        function SliderValueChanging(app, event)
        
        end

        % Selection changed function: ToolChangeButtonGroup
        function ToolChangeButtonGroupSelectionChanged(app, event)
            %selectedButton = app.ToolChangeButtonGroup.SelectedObject;
            if(app.Bit1Button.Value)
                angle = 0.25;
            elseif(app.Bit2Button.Value)
                angle = 0.5;
            elseif(app.Bit3Button.Value)
                angle = 0.75;
            else
                angle = 1.0;
            end
            writePosition(app.s,angle);
            %pause(2);
        end

        % Selection changed function: HeatProbeControlsButtonGroup
        function HeatProbeControlsButtonGroupSelectionChanged(app, event)
            selectedButton = app.HeatProbeControlsButtonGroup.SelectedObject;
            if(app.STARTButton_2.Value)
                writeline(app.a, '2'); %Turn off z motor
            end  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.VULCANGUIPanel = uipanel(app.UIFigure);
            app.VULCANGUIPanel.Title = 'VULCAN GUI';
            app.TabGroup = uitabgroup(app.VULCANGUIPanel);
            app.DrillTab = uitab(app.TabGroup);
            app.DrillTab.Title = 'Drill';
            app.DrillControlsButtonGroup = uibuttongroup(app.DrillTab);
            app.DrillControlsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DrillControlsButtonGroupSelectionChanged, true);
            app.DrillControlsButtonGroup.Title = 'Drill Controls';
            app.STARTButton = uitogglebutton(app.DrillControlsButtonGroup);
            app.STARTButton.Text = 'START';
            app.STOPButton = uitogglebutton(app.DrillControlsButtonGroup);
            app.STOPButton.Text = 'STOP';
            app.ToolChangeButton = uitogglebutton(app.DrillControlsButtonGroup);
            app.ToolChangeButton.Text = 'Tool Change';
            app.ToolChangeButton.Value = true;
            app.ToolChangeButtonGroup = uibuttongroup(app.DrillTab);
            app.ToolChangeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ToolChangeButtonGroupSelectionChanged, true);
            app.ToolChangeButtonGroup.Title = 'Tool Change';
            app.Bit1Button = uitogglebutton(app.ToolChangeButtonGroup);
            app.Bit1Button.Text = 'Bit 1';
            app.Bit2Button = uitogglebutton(app.ToolChangeButtonGroup);
            app.Bit2Button.Text = 'Bit 2';
            app.Bit3Button = uitogglebutton(app.ToolChangeButtonGroup);
            app.Bit3Button.Text = 'Bit 3';
            app.Bit4Button = uitogglebutton(app.ToolChangeButtonGroup);
            app.Bit4Button.Text = 'Bit 4';
            app.Bit4Button.Value = true;
            app.UITable = uitable(app.DrillTab);
            app.UITable.ColumnName = {'Start Time'; 'Duration (Sec)'; 'Voltage'};
            app.UITable.RowName = {};
            app.AmperageGaugeLabel = uilabel(app.DrillTab);
            app.AmperageGaugeLabel.Text = 'Amperage';
            app.AmperageGauge = uigauge(app.DrillTab, 'semicircular');
            app.UIAxes = uiaxes(app.DrillTab);
            title(app.UIAxes, 'Digital Core Sim')
            xlabel(app.UIAxes, 'Time (Distance (Z))')
            ylabel(app.UIAxes, {'Voltage (Weight on Bit)'; ''})
            app.HeatProbeTab = uitab(app.TabGroup);
            app.HeatProbeTab.Title = 'Heat Probe';
            app.HeatProbeControlsButtonGroup = uibuttongroup(app.HeatProbeTab);
            app.HeatProbeControlsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @HeatProbeControlsButtonGroupSelectionChanged, true);
            app.HeatProbeControlsButtonGroup.Title = 'Heat Probe Controls';
            app.STARTButton_2 = uitogglebutton(app.HeatProbeControlsButtonGroup);
            app.STARTButton_2.Text = 'START';
            app.STARTButton_2.Value = true;
            app.STOPButton_2 = uitogglebutton(app.HeatProbeControlsButtonGroup);
            app.STOPButton_2.Text = 'STOP';
            app.ToolChangeButton_2 = uitogglebutton(app.HeatProbeControlsButtonGroup);
            app.ToolChangeButton_2.Text = 'Tool Change';
            app.PumpTab = uitab(app.TabGroup);
            app.PumpTab.Title = 'Pump';
            app.PumpControlButtonGroup = uibuttongroup(app.PumpTab);
            app.PumpControlButtonGroup.Title = 'Pump Control';
            app.STARTButton_3 = uitogglebutton(app.PumpControlButtonGroup);
            app.STARTButton_3.Text = 'START';
            app.STARTButton_3.Value = true;
            app.STOPButton_3 = uitogglebutton(app.PumpControlButtonGroup);
            app.STOPButton_3.Text = 'STOP';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VULCAN_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end